% description: Copyright 2020 The MathWorks, Inc.
classdef COVID19_SocialDistancing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        CalibrationTab                 matlab.ui.container.Tab
        LoadSampleImageButton          matlab.ui.control.Button
        CalibrateforBirdEyeViewButton  matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        RunningModelTab                matlab.ui.container.Tab
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes_4                       matlab.ui.control.UIAxes
        RunSocialDistancingAppButton   matlab.ui.control.Button
        ExecutionSwitchLabel           matlab.ui.control.Label
        ExecutionSwitch                matlab.ui.control.Switch
        SocialDistancingDetectorDemoLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        I % Description
        s
        T
        RB
    end
    
    methods (Access = private)
        
        function [bboxes,scores,labels] = yolov3Detect(app, net, XTest, networkOutputs, anchors, anchorBoxMask, confidenceThreshold, overlapThreshold, classes)
        % The yolov3Detect function detects the bounding boxes, scores, and labels in an image.
        
        imageSize = size(XTest,[1,2]);
        
        % Find the input image layer and get the network input size.
        networkInputIdx = arrayfun( @(x)isa(x,'nnet.cnn.layer.ImageInputLayer'), net.Layers);
        networkInputSize = net.Layers(networkInputIdx).InputSize;
        
        % Predict and filter the detections based on confidence threshold.
        predictions = yolov3Predict(app, net,XTest,networkOutputs,anchorBoxMask);
        predictions = cellfun(@ gather, predictions,'UniformOutput',false);
        predictions = cellfun(@ extractdata, predictions, 'UniformOutput', false);
        tiledAnchors = generateTiledAnchors(app, predictions(:,2:5),anchors,anchorBoxMask);
        predictions(:,2:5) = applyAnchorBoxOffsets(app, tiledAnchors, predictions(:,2:5), networkInputSize);
        [bboxes,scores,labels] = generateYOLOv3Detections(predictions, confidenceThreshold, imageSize, classes);
        
        % Apply suppression to the detections to filter out multiple overlapping
        % detections.
        if ~isempty(scores)
            [bboxes, scores, labels] = selectStrongestBboxMulticlass(bboxes, scores, labels ,...
                'RatioType', 'Union', 'OverlapThreshold', overlapThreshold);
        end
        end
        
        function YPredCell = yolov3Predict(app, net,XTrain,networkOutputs,anchorBoxMask)
        % Predict the output of network and extract the confidence, x, y,
        % width, height, and class.
        YPredictions = cell(size(networkOutputs));
        [YPredictions{:}] = predict(net, XTrain);
        YPredCell = extractPredictions(app, YPredictions, anchorBoxMask);
        
        % Apply activation to the predicted cell array.
        YPredCell = applyActivations(app, YPredCell);
        end
        function YPredCell = applyActivations(app, YPredCell)
        YPredCell(:,1:3) = cellfun(@ sigmoid ,YPredCell(:,1:3),'UniformOutput',false);
        YPredCell(:,4:5) = cellfun(@ exp,YPredCell(:,4:5),'UniformOutput',false);
        YPredCell(:,6) = cellfun(@ sigmoid ,YPredCell(:,6),'UniformOutput',false);
        end
        
        function predictions = extractPredictions(app, YPredictions, anchorBoxMask)
        predictions = cell(size(YPredictions, 1),6);
        for ii = 1:size(YPredictions, 1)
            numAnchors = size(anchorBoxMask{ii},2);
            % Confidence scores.
            startIdx = 1;
            endIdx = numAnchors;
            predictions{ii,1} = YPredictions{ii}(:,:,startIdx:endIdx,:);
            
            % X positions.
            startIdx = startIdx + numAnchors;
            endIdx = endIdx+numAnchors;
            predictions{ii,2} = YPredictions{ii}(:,:,startIdx:endIdx,:);
            
            % Y positions.
            startIdx = startIdx + numAnchors;
            endIdx = endIdx+numAnchors;
            predictions{ii,3} = YPredictions{ii}(:,:,startIdx:endIdx,:);
            
            % Width.
            startIdx = startIdx + numAnchors;
            endIdx = endIdx+numAnchors;
            predictions{ii,4} = YPredictions{ii}(:,:,startIdx:endIdx,:);
            
            % Height.
            startIdx = startIdx + numAnchors;
            endIdx = endIdx+numAnchors;
            predictions{ii,5} = YPredictions{ii}(:,:,startIdx:endIdx,:);
            
            % Class probabilities.
            startIdx = startIdx + numAnchors;
            predictions{ii,6} = YPredictions{ii}(:,:,startIdx:end,:);
        end
        end
        
        function tiledAnchors = generateTiledAnchors(app, YPredCell,anchorBoxes,anchorBoxMask)
        % Generate tiled anchor offset.
        tiledAnchors = cell(size(YPredCell));
        for i=1:size(YPredCell,1)
            anchors = anchorBoxes(anchorBoxMask{i}, :);
            [h,w,~,n] = size(YPredCell{i,1});
            [tiledAnchors{i,2}, tiledAnchors{i,1}] = ndgrid(0:h-1,0:w-1,1:size(anchors,1),1:n);
            [~,~,tiledAnchors{i,3}] = ndgrid(0:h-1,0:w-1,anchors(:,2),1:n);
            [~,~,tiledAnchors{i,4}] = ndgrid(0:h-1,0:w-1,anchors(:,1),1:n);
        end
        end
        
        function tiledAnchors = applyAnchorBoxOffsets(app, tiledAnchors,YPredCell,inputImageSize)
        % Convert grid cell coordinates to box coordinates.
        for i=1:size(YPredCell,1)
            [h,w,~,~] = size(YPredCell{i,1});  
            tiledAnchors{i,1} = (tiledAnchors{i,1}+YPredCell{i,1})./w;
            tiledAnchors{i,2} = (tiledAnchors{i,2}+YPredCell{i,2})./h;
            tiledAnchors{i,3} = (tiledAnchors{i,3}.*YPredCell{i,3})./inputImageSize(2);
            tiledAnchors{i,4} = (tiledAnchors{i,4}.*YPredCell{i,4})./inputImageSize(1);
        end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadSampleImageButton
        function LoadSampleImageButtonPushed(app, event)
            app.I = imread(fullfile([pwd, '\dataset\Crowd_PETS09\S2\L1\Time_12-34\View_001\frame_0000.jpg']));
            imshow(app.I, 'Parent', app.UIAxes);
        end

        % Button pushed function: CalibrateforBirdEyeViewButton
        function CalibrateforBirdEyeViewButtonPushed(app, event)
            h = drawpolyline(app.UIAxes, 'Color','green');
            Porig = h.Position;
            sz = size(app.I);
            Ppost = [1 1; sz(1) 1; sz(1) sz(2); 1 sz(2)];

            app.T = fitgeotrans(Porig, Ppost,'projective'); 
            [IBird, app.RB] = imwarp(app.I,app.T);
            Psel = reshape(Porig', 1, []);
            Ori = insertShape(app.I, 'FilledPolygon', Psel, 'Opacity', 0.5);
            [x, y] = transformPointsForward(app.T, Porig(:,1), Porig(:, 2));
            [xdataI, ydataI]=worldToIntrinsic(app.RB,x,y);
            IBird2 = insertShape(IBird, 'FilledPolygon', reshape([xdataI, ydataI]', 1, []), "Opacity",0.5);
            imshow(Ori, 'Parent', app.UIAxes)
            imshow(IBird2, 'Parent', app.UIAxes_2);
            set(app.UIAxes_3, 'xlim', [size(IBird2, 2)*2/3 size(IBird2, 2)])
            set(app.UIAxes_3, 'ylim', [size(IBird2, 1)/3 size(IBird2, 1)])
        end

        % Button pushed function: RunSocialDistancingAppButton
        function RunSocialDistancingAppButtonPushed(app, event)
            app.s = load('PedYOLOv3.mat');
            filename = 'dataset\sample.avi';
            v = VideoReader(filename, 'CurrentTime', 15);
            cnt = 0;
            while hasFrame(v)
                frame = readFrame(v);
                sz = size(frame);
                resz = [227 227];
                frame1 = im2single(imresize(frame, resz));
                % Convert to dlarray.
                execenv = app.ExecutionSwitch.Value;
                if execenv == 'GPU'
                    XTest = gpuArray(dlarray(frame1,'SSCB'));
                else
                    XTest = dlarray(frame1, 'SSCB');
                end
                [bbox, ~, ~] = yolov3Detect(app, app.s.net, XTest, app.s.networkOutputs, app.s.anchorBoxes, app.s.anchorBoxMasks, ...
                    0.5, 0.4, app.s.classNames);
                % fps
                bbox(:,1) = bbox(:,1)*sz(2)/resz(2);
                bbox(:,2) = bbox(:,2)*sz(1)/resz(1);
                bbox(:,3) = bbox(:,3)*sz(2)/resz(2);
                bbox(:,4) = bbox(:,4)*sz(1)/resz(1);
                
                detectedImg = frame;

                bottom_center = [bbox(:,1)+bbox(:,3)/2, bbox(:, 2) + bbox(:,4)];
                [x, y] = transformPointsForward(app.T, bottom_center(:,1), bottom_center(:, 2));
                [xdataI,ydataI] = worldToIntrinsic(app.RB,x,y);
                d = pdist2([xdataI,ydataI], [xdataI,ydataI]);
                d = triu(d);
                [r, c] = find(d<0.2e3 & d>0);
                idx = [r;c];
                
                plot(app.UIAxes_3, xdataI, ydataI, 'bo', 'MarkerFaceColor',"b", 'MarkerSize', 10); hold(app.UIAxes_3, 'on');
                plot(app.UIAxes_3, xdataI(idx), ydataI(idx), 'ro', 'MarkerFaceColor',"r", 'MarkerSize', 10); hold(app.UIAxes_3, 'off');
                for i = 1:length(r)
                    line(app.UIAxes_3, xdataI([r(i);c(i)]), ydataI([r(i);c(i)]),'color','r')
                end
                if ~isempty(bbox)
                    for idx=1:size(bbox, 1)
                        annotation = sprintf('%s', 'People');
                        bboxf = bbox(idx, :);
                        if ~any(ismember([r;c], idx))
                            detectedImg = insertObjectAnnotation(detectedImg, 'rectangle', bboxf, annotation, 'LineWidth', 3);
                        else
                            detectedImg = insertObjectAnnotation(detectedImg, 'rectangle', bboxf, annotation, 'LineWidth', 3, 'color', 'r');
                        end 
                    end
                end
                I1 = imshow(detectedImg, 'Parent', app.UIAxes_4, 'XData', [1 app.UIAxes_4.Position(3)], 'YData', [1 app.UIAxes_4.Position(4)]);
                drawnow limitrate;
                if cnt == 0
                    app.UIAxes_4.XLim = [0 I1.XData(2)];
                    app.UIAxes_4.YLim = [0 I1.YData(2)];
                    cnt = cnt+1;
                end               
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.LoadSampleImageButton = uibutton(app.CalibrationTab, 'push');
            app.LoadSampleImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSampleImageButtonPushed, true);
            app.LoadSampleImageButton.Text = 'Load Sample Image';
            app.CalibrateforBirdEyeViewButton = uibutton(app.CalibrationTab, 'push');
            app.CalibrateforBirdEyeViewButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateforBirdEyeViewButtonPushed, true);
            app.CalibrateforBirdEyeViewButton.Text = 'Calibrate for Bird-Eye View';
            app.UIAxes = uiaxes(app.CalibrationTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.CalibrationTab);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.RunningModelTab = uitab(app.TabGroup);
            app.RunningModelTab.Title = 'Running Model';
            app.UIAxes_3 = uiaxes(app.RunningModelTab);
            title(app.UIAxes_3, 'Bird''s-Eye View')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, '')
            app.UIAxes_3.XLim = [700 1400];
            app.UIAxes_3.YLim = [700 1800];
            app.UIAxes_3.YDir = 'reverse';
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_4 = uiaxes(app.RunningModelTab);
            title(app.UIAxes_4, '')
            xlabel(app.UIAxes_4, '')
            ylabel(app.UIAxes_4, '')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.RunSocialDistancingAppButton = uibutton(app.RunningModelTab, 'push');
            app.RunSocialDistancingAppButton.ButtonPushedFcn = createCallbackFcn(app, @RunSocialDistancingAppButtonPushed, true);
            app.RunSocialDistancingAppButton.Text = 'Run Social Distancing App';
            app.ExecutionSwitchLabel = uilabel(app.RunningModelTab);
            app.ExecutionSwitchLabel.Text = 'Execution';
            app.ExecutionSwitch = uiswitch(app.RunningModelTab, 'slider');
            app.ExecutionSwitch.Items = {'CPU', 'GPU'};
            app.ExecutionSwitch.Value = 'CPU';
            app.SocialDistancingDetectorDemoLabel = uilabel(app.GridLayout);
            app.SocialDistancingDetectorDemoLabel.Text = 'Social Distancing Detector Demo ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = COVID19_SocialDistancing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end